classdef BIOENG_2351_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        EndWorkoutButton                matlab.ui.control.Button
        SensorsCalibratedLabel          matlab.ui.control.Label
        TargetZoneDropDown              matlab.ui.control.DropDown
        TargetZoneDropDownLabel         matlab.ui.control.Label
        ZonesTable                      matlab.ui.control.Table
        ComputeZonesButton              matlab.ui.control.Button
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Beginthetreadmillatapaceof20minmileandwalknormally2PressStartWorkoutandcontinuetowalknormallyfor5stocalibratethesensorsLabel  matlab.ui.control.Label
        CalibrateSensorsBeginWorkoutLabel  matlab.ui.control.Label
        WelcometoPantheRunpleaseletscomeupwithadecentnameLabel  matlab.ui.control.Label
        DAQStartupStatus                matlab.ui.control.Label
        StartDAQsButton                 matlab.ui.control.Button
        ConnectedDAQStatusLabel         matlab.ui.control.Label
        ArduinoPortStatusLabel          matlab.ui.control.Label
        ChooseWorkoutSettingsLabel      matlab.ui.control.Label
        SelectArduinoPortDropDown       matlab.ui.control.DropDown
        SelectArduinoPortDropDownLabel  matlab.ui.control.Label
        SelectDAQDeviceDropDown         matlab.ui.control.DropDown
        SelectDAQDeviceDropDownLabel    matlab.ui.control.Label
        StartWorkoutButton              matlab.ui.control.Button
        SearchforDAQsButton             matlab.ui.control.Button
        ConnectDataAcquisitionEquipmentLabel  matlab.ui.control.Label
        MainTab                         matlab.ui.container.Tab
        FeedbackPanel                   matlab.ui.container.Panel
        ExampleYourformispoorandyourefallingbehindCorrectyourformtocatchbackupLabel  matlab.ui.control.Label
        EndWorkoutButton_2              matlab.ui.control.Button
        LiveFeedbackPanel               matlab.ui.container.Panel
        maybewecanreplacethispanelwiththeraceideaLabel  matlab.ui.control.Label
        HRAxes                          matlab.ui.control.UIAxes
        CadenceAxes                     matlab.ui.control.UIAxes
        MetricsPanel                    matlab.ui.container.Panel
        CurrentCadenceLabel             matlab.ui.control.Label
        CurrentCadenceText              matlab.ui.control.Label
        CurrentPaceLabel                matlab.ui.control.Label
        CurrentPaceText                 matlab.ui.control.Label
        CurrentSpO2Label                matlab.ui.control.Label
        CurrentSpO2Text                 matlab.ui.control.Label
        CurrentHRLabel                  matlab.ui.control.Label
        CurrentHRText                   matlab.ui.control.Label
        GuidanceTextArea                matlab.ui.control.TextArea
        GuidanceTextAreaLabel           matlab.ui.control.Label
        TargetHeartRatebpmEditField     matlab.ui.control.NumericEditField
        TargetHeartRatebpmEditFieldLabel  matlab.ui.control.Label
        HistoryTab                      matlab.ui.container.Tab
        YourWorkoutHistoryLabel         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        %% DAQ objects and constants:
        selected_daq        % Selected NI DAQ device (e.g. "Dev15")
        selected_ard        % Selected Arduino port (e.g. "COM4")
        nidaq               % Currently running NI DAQ device
        nidaq_fs = 300;     % NI DAQ sampling rate
        arduino             % Currently running arduino device
        ard_fs = 20;        % Arduino sampling rate
        d_hr                % Digital filt object for PPG data
        d_acc               % Digital filt object for acceleration data
        sec_to_plot = 5     % Seconds to plot for

        %% Data buffers (contantly updating):
        spo2                % Current blood oxygen value (float)
        ts_acc_buffer       % Current vector of timestamps for acc data
        x_acc_buffer        % Current vector of x-axis acceleration data
        y_acc_buffer        % Current vector of y-axis acceleration data
        mag_acc_buffer% (is it necessary??)
        ts_ppg              % Current vector of timestamps for ppg data
        ppg                 % Current vector of ppg magnitude data
        heartrate           % Current heartrate value (float)
        cadence             % Current cadence value (float)
        pace                % Current pace value (float)
        
        %% HR Zones
        HRmax double = NaN               % computed with 220 - age
        Zones table                      % full zones table (text + numeric)
        TargetZone struct = struct('name',"", 'pct',[NaN NaN], 'bpm',[NaN NaN])

        %% Accelerometer Calibration
        isCalibrating = true   
        pace_calib

    end
    
    methods (Access = private)
        function hrmax = computeHRmax_220minusAge(app, age)
        % Enforce reasonable bounds
        age = max(10, min(100, double(age)));
        hrmax = 220 - age;
    end

    function Z = makeZones(app, hrmax)
        % Percent bands (match your color graphic)
        names = ["Very Light","Light","Moderate","Hard","Maximum"]';
        pct    = [0.50 0.60; 0.60 0.70; 0.70 0.80; 0.80 0.90; 0.90 1.00];
        desc   = [ ...
            "Helps with recovery"; ...
            "Improves basic endurance / fat burning (easy jog / brisk walk)"; ...
            "Improves aerobic fitness (steady run)"; ...
            "Increases max capacity (tempo/intervals)"; ...
            "Develops speed (sprints / very hard)" ...
        ];
        bpm = round(pct * hrmax);
        pctStr   = arrayfun(@(i)sprintf('%d–%d%%',round(100*pct(i,1)),round(100*pct(i,2))), (1:5)','uni',0);
        rangeStr = arrayfun(@(i)sprintf('%d–%d',bpm(i,1),bpm(i,2)), (1:5)','uni',0);

        Z = table(names, pctStr, rangeStr, desc, ...
            'VariableNames', {'Zone','PercentHRmax','Range_bpm','Intensity'});
        % Keep numeric copies for control logic
        Z.NumericPct = pct;   % 5x2
        Z.NumericBpm = bpm;   % 5x2
    end

    function colorizeZoneRows(app)
        % Apply row colors to ZonesTable using uistyle
        T = app.ZonesTable;
        removeStyle(T) % clear old styles
        colors = [...
            0.85 0.85 0.85;  % Very Light (gray)
            0.80 0.87 1.00;  % Light (blue)
            0.83 0.93 0.83;  % Moderate (green)
            1.00 0.94 0.75;  % Hard (yellow)
            1.00 0.80 0.80]; % Maximum (red)
        for r = 1:size(app.Zones,1)
            addStyle(T, uistyle('BackgroundColor', colors(r,:)), 'row', r);
        end
    end

    function setTargetFromDropdown(app)
        if isempty(app.Zones) || any(ismissing(app.Zones.Zone))
            return
        end
        % Map dropdown to row index
        map = containers.Map(...
            ["Very Light (50-60%)","Light (60-70%)","Moderate (70-80%)","Hard (80-90%)","Maximum (90-100%)"], ...
            num2cell(1:5));
        idx = map(string(app.TargetZoneDropDown.Value));
        app.TargetZone.name = string(app.Zones.Zone(idx));
        app.TargetZone.pct  = app.Zones.NumericPct(idx,:);
        app.TargetZone.bpm  = app.Zones.NumericBpm(idx,:);
    end
        %% Main Function:
        function mainFcn(app, src)
            % Main callback for ScansAvailableFcn: continuous loop

            %% NI DAQ Acquisition:
            [data, ts, ~] = read(src, src.ScansAvailableFcnCount, OutputFormat='Matrix');
            try
                % Acquire data and update buffers:
                x_acc = (data(:,1) - 3/2) ./ 0.42;
                y_acc = (data(:,2) - 3/2) ./ 0.42;
                mag_acc = sqrt(x_acc.^2 + y_acc.^2); 

                app.x_acc_buffer = [app.x_acc_buffer; x_acc];
                app.y_acc_buffer = [app.y_acc_buffer; y_acc];
                app.ts_acc_buffer = [app.ts_acc_buffer; ts];
                app.mag_acc_buffer = [app.mag_acc_buffer; mag_acc];
            catch ME
                fprintf("\n\tError in NIDAQ data acquisition:");
                fprintf("\n\tMessage: %s", ME.message);
            end

            %% Arduino Acquisition:
            if app.arduino.NumBytesAvailable > 0
                try 
                    % Acquire data and update buffers:
                    line = readline(app.arduino);
                    ard_data = str2double(split(line, ','));
                    validateSpO2(app, ard_data(end));

                    %validateSpO2(app, str2double(ard_data(end)));
                    app.ppg = ard_data(1:end-1);
                    app.ts_ppg = linspace(app.ts_acc_buffer(end)-length(app.ppg) ...
                        /app.ard_fs, app.ts_acc_buffer(end), length(app.ppg));
                catch ME
                    fprintf("\n\tError in Arduino data acquisition:");
                    fprintf("\n\tMessage: %s", ME.message);
                end
            end

            %% Process Data:
            % Here update plots and compute metrics:
            if ~isempty(app.ppg)
                plotPPG(app)
                app.heartrate = heartRateFcn(app.ts_ppg, app.ppg, app.d_hr);
                app.CurrentHRLabel.Text = string(app.heartrate);
            end

            if ~isempty(app.x_acc_buffer)
                plotAcc(app)
                filtered_y = filtfilt(app.d_acc, app.y_acc_buffer);
                app.cadence = cadenceFcn(app.ts_acc_buffer, filtered_y);
                app.CurrentCadenceLabel.Text = string(app.cadence);
            end

            if ~isempty(app.spo2)
                if isnumeric(app.spo2)
                    app.CurrentSpO2Label.Text = string(app.spo2);
            
                end
            end

            if app.isCalibrating == true && length(app.ts_acc_buffer) >= 5*app.nidaq_fs
                filtered_acc = filtfilt(app.d_acc, app.mag_acc_buffer);
                app.pace_calib = calibrateAccelerometerFcn(app.ts_acc_buffer, filtered_acc, 20);
                app.isCalibrating = false;
                app.SensorsCalibratedLabel.Visible = 'on';
                %disp(app.pace_calib);
            elseif app.isCalibrating == false
                % should probably move this to the plotting function so
                % that it's not being called every single time. once every
                % 5s when the buffer fills would be enough i think
                filtered_acc = filtfilt(app.d_acc, app.mag_acc_buffer);
                app.pace = paceFcn(app.ts_acc_buffer, filtered_acc, app.pace_calib);
                %app.CurrentPaceLabel.Text = horzcat(string(floor(app.pace)),".",string(round(mod(app.Pace,1)*60))); % need to convert to min+sec (8.50 -> 8:30 e.g.)
                app.CurrentPaceLabel.Text = string(app.pace);
                %disp(app.pace);
            end

            %% Main Algorithm:
            % Here will be our main algorithm

        end
        
        %% Helper Functions:
        function createHRFilter(app)
            % Design a highpass filter for PPG data
            %
            % Returns digital filter object d_hr

            Fs = app.ard_fs;
            Fc = 0.4;
        
            app.d_hr = designfilt('highpassiir', ...
                'FilterOrder', 3, ...
                'HalfPowerFrequency', Fc, ...
                'SampleRate', Fs, ...
                'DesignMethod', 'Butter');
        end
        
        function createAccFilter(app)
            % Design a filter for the acceleration data
            %
            % Returns digital filter object d_acc

            Fs = app.nidaq_fs;
            Fc = 6;

            app.d_acc = designfilt('lowpassiir', ...
                'FilterOrder', 3, ...
                'HalfPowerFrequency', Fc, ...
                'SampleRate', Fs, ...
                'DesignMethod', 'Butter');
        end

        function plotPPG(app)
            % Plot the filtered PPG signal on the chosen plot

            filt_ppg = filtfilt(app.d_hr, app.ppg);
            plot(app.HRAxes, app.ts_ppg, filt_ppg);
            if length(app.ppg) > (1+app.sec_to_plot)*app.ard_fs
                app.ppg = app.ppg(app.ard_fs:end);
                app.ts_ppg = app.ts_ppg(app.ard_fs:end);
            end
            xlim(app.HRAxes, "tight");
            ylim(app.HRAxes, "tight");
        end

        function plotAcc(app)
            % Plot the filtered acc signal on the chosen plot

            filt_acc = filtfilt(app.d_acc, app.y_acc_buffer);
            plot(app.CadenceAxes, app.ts_acc_buffer, filt_acc);

            if length(app.y_acc_buffer) > (1+app.sec_to_plot)*app.nidaq_fs
                app.y_acc_buffer = app.y_acc_buffer(app.nidaq_fs:end);
                app.ts_acc_buffer = app.ts_acc_buffer(app.nidaq_fs:end);
                app.mag_acc_buffer = app.mag_acc_buffer(app.nidaq_fs:end);
            end
            
            if app.ts_acc_buffer(1) >= app.sec_to_plot

                xlim(app.CadenceAxes, [app.ts_acc_buffer(end-app.sec_to_plot*app.nidaq_fs), app.ts_acc_buffer(end)])

            end
            ylim(app.CadenceAxes, "tight")
        end
        
        function validateSpO2(app, spo2)
            if spo2 > 0 && spo2 <= 100
                app.spo2 = spo2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchforDAQsButton
        function searchForDAQs(app, event)
            % Search for DAQs and returns available COM (Serial) and DAQ
            % objects for user to select

            % Enable dropdown menus once button pressed:
            app.SelectDAQDeviceDropDown.Enable = "on";
            app.SelectArduinoPortDropDown.Enable = "on";

            % DAQ handler:
            avail_daqs = daqlist;
            if isempty(avail_daqs)
                app.ConnectedDAQStatusLabel.Text = 'No connected DAQ devices found.';
            else
                device_ids = avail_daqs.DeviceID;
                app.SelectDAQDeviceDropDown.Items = ["<Select>"; device_ids];
            end
            
            % Serial (Arduino) handler:
            avail_coms = serialportlist;
            if isempty(avail_coms)
                app.ArduinoPortStatusLabel.Text = 'No active COM (USB) ports found.';
            else
                app.SelectArduinoPortDropDown.Items = ["<Select>", avail_coms];
            end

            % Once user makes selections, let them start the DAQs
            app.StartDAQsButton.Enable = "on";
        end

        % Button pushed function: StartDAQsButton
        function daqSetup(app, event)
            % Connects to selected DAQ/Arduino devices
            try
                % Attempt connection
                app.nidaq = daq("ni");
                addinput(app.nidaq, app.selected_daq, "ai0", "Voltage");
                addinput(app.nidaq, app.selected_daq, "ai4", "Voltage");
               
                app.nidaq.Rate = app.nidaq_fs;
                app.nidaq.ScansAvailableFcnCount = app.nidaq_fs/10; % effective plot refresh rate
    
                app.arduino = serialport(app.selected_ard, 230400);
                configureTerminator(app.arduino, "LF");
                flush(app.arduino);
    
                app.DAQStartupStatus.FontColor = [0.23,0.67,0.20];
                app.DAQStartupStatus.Text = 'DAQs Connected Successfully';
                app.SearchforDAQsButton.Enable = "off"; % Disable options
                app.StartDAQsButton.Enable = "off";
                app.SelectArduinoPortDropDown.Enable = "off";
                app.SelectDAQDeviceDropDown.Enable = "off";
                app.StartWorkoutButton.Enable ="on";
            catch
                app.DAQStartupStatus.FontColor = [1 0 0];
                app.DAQStartupStatus.Text = "Error encountered: Check selected ports/devices";
            end
        end

        % Value changed function: SelectDAQDeviceDropDown
        function daqSelectionFcn(app, event)
            value = app.SelectDAQDeviceDropDown.Value;
            app.selected_daq = string(value);
        end

        % Value changed function: SelectArduinoPortDropDown
        function ardSelectionFcn(app, event)
            value = app.SelectArduinoPortDropDown.Value;
            app.selected_ard = string(value);
        end

        % Button pushed function: StartWorkoutButton
        function startWorkout(app, event)
            app.nidaq.ScansAvailableFcn = @(src, evt) mainFcn(app, src);
            start(app.nidaq, "continuous");
            createHRFilter(app);
            createAccFilter(app);
            app.StartWorkoutButton.Enable = 'off';
            app.EndWorkoutButton.Enable = 'on';      % setup tab button
            app.EndWorkoutButton_2.Enable = 'on';    % main tab button
        end

        % Button pushed function: ComputeZonesButton
        function ComputeZonesButtonPushed(app, event)
            age = app.AgeEditField.Value;
            if isempty(age) || ~isfinite(age) || age <= 0
                uialert(app.UIFigure,'Enter a valid age (>0).','Age needed'); 
                return;
            end
        
            % ---- 220 - age method ----
            app.HRmax = app.computeHRmax_220minusAge(age);
        
            % Build zones, show in table, color rows
            app.Zones = app.makeZones(app.HRmax);
            app.ZonesTable.Data = app.Zones(:,1:4);  % text columns only
            app.colorizeZoneRows();
        
            % Default target zone = Moderate
            app.TargetZoneDropDown.Value = "Moderate (70-80%)";
            app.setTargetFromDropdown();
        end

        % Value changed function: TargetZoneDropDown
        function TargetZoneDropDownValueChanged(app, event)
             if isnan(app.HRmax); return; end
             app.setTargetFromDropdown();
            
        end

        % Button pushed function: EndWorkoutButton, EndWorkoutButton_2
        function endWorkoutCallback(app, event)
            stop(app.nidaq);
            flush(app.nidaq);
            flush(app.arduino);

            % here we can have code for saving the workout or displaying a
            % summary maybe
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectDataAcquisitionEquipmentLabel = uilabel(app.SetupTab);
            app.ConnectDataAcquisitionEquipmentLabel.Text = 'Connect Data Acquisition Equipment';
            app.SearchforDAQsButton = uibutton(app.SetupTab, 'push');
            app.SearchforDAQsButton.ButtonPushedFcn = createCallbackFcn(app, @searchForDAQs, true);
            app.SearchforDAQsButton.Text = 'Search for DAQs';
            app.StartWorkoutButton = uibutton(app.SetupTab, 'push');
            app.StartWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @startWorkout, true);
            app.StartWorkoutButton.Enable = 'off';
            app.StartWorkoutButton.Text = 'Start Workout';
            app.SelectDAQDeviceDropDownLabel = uilabel(app.SetupTab);
            app.SelectDAQDeviceDropDownLabel.Enable = 'off';
            app.SelectDAQDeviceDropDownLabel.Text = 'Select DAQ Device';
            app.SelectDAQDeviceDropDown = uidropdown(app.SetupTab);
            app.SelectDAQDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @daqSelectionFcn, true);
            app.SelectDAQDeviceDropDown.Enable = 'off';
            app.SelectDAQDeviceDropDown.Tooltip = {'Select the DAQ device to use (e.g. "Dev15").'};
            app.SelectArduinoPortDropDownLabel = uilabel(app.SetupTab);
            app.SelectArduinoPortDropDownLabel.Enable = 'off';
            app.SelectArduinoPortDropDownLabel.Text = 'Select Arduino Port';
            app.SelectArduinoPortDropDown = uidropdown(app.SetupTab);
            app.SelectArduinoPortDropDown.ValueChangedFcn = createCallbackFcn(app, @ardSelectionFcn, true);
            app.SelectArduinoPortDropDown.Enable = 'off';
            app.SelectArduinoPortDropDown.Tooltip = {'Select the COM (USB) port to which the Arduino is connected (e.g. "COM4").'};
            app.ChooseWorkoutSettingsLabel = uilabel(app.SetupTab);
            app.ChooseWorkoutSettingsLabel.Text = 'Choose Workout Settings';
            app.ArduinoPortStatusLabel = uilabel(app.SetupTab);
            app.ArduinoPortStatusLabel.Text = '';
            app.ConnectedDAQStatusLabel = uilabel(app.SetupTab);
            app.ConnectedDAQStatusLabel.Text = '';
            app.StartDAQsButton = uibutton(app.SetupTab, 'push');
            app.StartDAQsButton.ButtonPushedFcn = createCallbackFcn(app, @daqSetup, true);
            app.StartDAQsButton.Enable = 'off';
            app.StartDAQsButton.Text = 'Start DAQs';
            app.DAQStartupStatus = uilabel(app.SetupTab);
            app.DAQStartupStatus.Text = '';
            app.WelcometoPantheRunpleaseletscomeupwithadecentnameLabel = uilabel(app.SetupTab);
            app.WelcometoPantheRunpleaseletscomeupwithadecentnameLabel.Text = 'Welcome to PantheRun! (please let''s come up with a decent name)';
            app.CalibrateSensorsBeginWorkoutLabel = uilabel(app.SetupTab);
            app.CalibrateSensorsBeginWorkoutLabel.Text = 'Calibrate Sensors & Begin Workout';
            app.Beginthetreadmillatapaceof20minmileandwalknormally2PressStartWorkoutandcontinuetowalknormallyfor5stocalibratethesensorsLabel = uilabel(app.SetupTab);
            app.Beginthetreadmillatapaceof20minmileandwalknormally2PressStartWorkoutandcontinuetowalknormallyfor5stocalibratethesensorsLabel.Text = {'1. Begin the treadmill at a pace of 20 min/mile and walk normally.'; '2. Press Start Workout and continue to walk normally for 5s to calibrate the sensors.'};
            app.Image = uiimage(app.SetupTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Sensor Setup Pic.png');
            app.AgeEditFieldLabel = uilabel(app.SetupTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.SetupTab, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.AgeEditField.RoundFractionalValues = 'on';
            app.ComputeZonesButton = uibutton(app.SetupTab, 'push');
            app.ComputeZonesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeZonesButtonPushed, true);
            app.ComputeZonesButton.Text = 'Compute Zones';
            app.ZonesTable = uitable(app.SetupTab);
            app.ZonesTable.ColumnName = {'Zone'; '%HRmax'; 'Range (bpm)'; 'Intensity/Activity'};
            app.ZonesTable.RowName = {};
            app.ZonesTable.ColumnEditable = [false false false false];
            app.TargetZoneDropDownLabel = uilabel(app.SetupTab);
            app.TargetZoneDropDownLabel.Text = 'Target Zone';
            app.TargetZoneDropDown = uidropdown(app.SetupTab);
            app.TargetZoneDropDown.Items = {'Very Light (50-60%)', 'Light (60-70%)', 'Moderate (70-80%)', 'Hard (80-90%)', 'Maximum (90-100%)'};
            app.TargetZoneDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetZoneDropDownValueChanged, true);
            app.TargetZoneDropDown.Value = 'Very Light (50-60%)';
            app.SensorsCalibratedLabel = uilabel(app.SetupTab);
            app.SensorsCalibratedLabel.Visible = 'off';
            app.SensorsCalibratedLabel.Text = 'Sensors calibrated successfully: please begin your workout!';
            app.EndWorkoutButton = uibutton(app.SetupTab, 'push');
            app.EndWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @endWorkoutCallback, true);
            app.EndWorkoutButton.Enable = 'off';
            app.EndWorkoutButton.Text = 'End Workout';
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MetricsPanel = uipanel(app.MainTab);
            app.MetricsPanel.Title = 'Metrics';
            app.TargetHeartRatebpmEditFieldLabel = uilabel(app.MetricsPanel);
            app.TargetHeartRatebpmEditFieldLabel.Text = 'Target Heart Rate (bpm)';
            app.TargetHeartRatebpmEditField = uieditfield(app.MetricsPanel, 'numeric');
            app.TargetHeartRatebpmEditField.Limits = [60 210];
            app.TargetHeartRatebpmEditField.Value = 165;
            app.GuidanceTextAreaLabel = uilabel(app.MetricsPanel);
            app.GuidanceTextAreaLabel.Text = 'Guidance';
            app.GuidanceTextArea = uitextarea(app.MetricsPanel);
            app.GuidanceTextArea.Editable = 'off';
            app.GuidanceTextArea.Value = {'Increase cadence and take deeper breaths to lower heart rate'};
            app.CurrentHRText = uilabel(app.MetricsPanel);
            app.CurrentHRText.Text = 'Current Heart Rate';
            app.CurrentHRLabel = uilabel(app.MetricsPanel);
            app.CurrentSpO2Text = uilabel(app.MetricsPanel);
            app.CurrentSpO2Text.Text = 'Current SpO2';
            app.CurrentSpO2Label = uilabel(app.MetricsPanel);
            app.CurrentSpO2Label.Text = '<Placeholder>';
            app.CurrentPaceText = uilabel(app.MetricsPanel);
            app.CurrentPaceText.Text = 'Current Pace';
            app.CurrentPaceLabel = uilabel(app.MetricsPanel);
            app.CurrentPaceLabel.Text = '<Placeholder>';
            app.CurrentCadenceText = uilabel(app.MetricsPanel);
            app.CurrentCadenceText.Text = 'Current Cadence';
            app.CurrentCadenceLabel = uilabel(app.MetricsPanel);
            app.CurrentCadenceLabel.Text = '<Placeholder>';
            app.LiveFeedbackPanel = uipanel(app.MainTab);
            app.LiveFeedbackPanel.Title = 'Live Feedback';
            app.CadenceAxes = uiaxes(app.LiveFeedbackPanel);
            title(app.CadenceAxes, 'Cadence')
            ylabel(app.CadenceAxes, 'spm')
            zlabel(app.CadenceAxes, 'Z')
            app.HRAxes = uiaxes(app.LiveFeedbackPanel);
            title(app.HRAxes, 'Heart Rate')
            xlabel(app.HRAxes, 'Time (s)')
            ylabel(app.HRAxes, 'bpm')
            zlabel(app.HRAxes, 'Z')
            app.maybewecanreplacethispanelwiththeraceideaLabel = uilabel(app.LiveFeedbackPanel);
            app.maybewecanreplacethispanelwiththeraceideaLabel.Text = 'maybe we can replace this panel with the "race" idea';
            app.EndWorkoutButton_2 = uibutton(app.MainTab, 'push');
            app.EndWorkoutButton_2.ButtonPushedFcn = createCallbackFcn(app, @endWorkoutCallback, true);
            app.EndWorkoutButton_2.Enable = 'off';
            app.EndWorkoutButton_2.Text = 'End Workout';
            app.FeedbackPanel = uipanel(app.MainTab);
            app.FeedbackPanel.Title = 'Feedback';
            app.ExampleYourformispoorandyourefallingbehindCorrectyourformtocatchbackupLabel = uilabel(app.FeedbackPanel);
            app.ExampleYourformispoorandyourefallingbehindCorrectyourformtocatchbackupLabel.Text = 'Example: Your form is poor and you''re falling behind! Correct your form to catch back up.';
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.UITable = uitable(app.HistoryTab);
            app.UITable.ColumnName = {'Date'; 'Workout Time'; 'Average Heart Rate'; 'See More'};
            app.UITable.RowName = {};
            app.YourWorkoutHistoryLabel = uilabel(app.HistoryTab);
            app.YourWorkoutHistoryLabel.Text = 'Your Workout History';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BIOENG_2351_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end